classdef usecase1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        VirtualTrekkingPanel         matlab.ui.container.Panel
        stepsmoretogoTextArea        matlab.ui.control.TextArea
        stepsmoretogoTextAreaLabel   matlab.ui.control.Label
        RESETDATAButton              matlab.ui.control.Button
        UIAxes                       matlab.ui.control.UIAxes
        VirtualHikingChallengePanel  matlab.ui.container.Panel
        Label                        matlab.ui.control.Label
        STARTCHALLENGEButton         matlab.ui.control.Button
        MountandDewitLabel           matlab.ui.control.Label
        MtEverestDropDown            matlab.ui.control.DropDown
    end

    
    properties (Access = private)
         mountains = struct('Name', {'Mt. Everest, Nepal', 'Kilimanjaro, Tanzania', 'Annapurna Circuit, Nepal' , 'Grand Canyon, USA', 'Inca Trail, Peru', 'Test Trek'}, 'TotalSteps', [58000, 49000, 62000, 3000, 27000, 200]);
         currentMountainIndex = 1;
         stepData; %to store acceleration data
         userSteps = 0;
         stepTimes = [];
        
    end
    
    methods (Access = private)
        
        function TrekkingLocationChanged(app)
            selectedMountain = app.MtEverestDropDown.Value;
            app.currentMountainIndex = find(strcmp({app.mountains.Name}, selectedMountain));
            
        end
        
        function calculateSteps(app)
            if isempty(app.stepData)
                uialert(app.UIFigure, 'No step data available', 'Error');
                return;
            end

    
            accelZ = app.stepData.Z;
            timeStamps = app.stepData.Timestamp; 

    
            meanZ = mean(accelZ);
            stdZ = std(accelZ);
            threshold = meanZ + stdZ; 

            stepCount = 0;

  
            for i = 2:height(app.stepData)
                if accelZ(i) > threshold && accelZ(i-1) <= threshold
                    stepCount = stepCount + 1;
                    app.stepTimes = [app.stepTimes; timeStamps(i)];
                    fprintf('Step detected at time: %s\n', datetime(timeStamps(i)));
                end
            end

       
            app.userSteps = stepCount;

        
            app.updateProgress();
            
        end
        
    end
    
    methods (Access = public)
        
        
        function updateProgress(app)
            
            mountain = app.mountains(app.currentMountainIndex);
            stepsLeft = mountain.TotalSteps - app.userSteps;

  
            stepTimesInSeconds = seconds(app.stepTimes - app.stepTimes(1)); 

    
            binEdges = 0:10:max(stepTimesInSeconds) + 10; 

            [counts, edges] = histcounts(stepTimesInSeconds, binEdges);
    
  
            bar(app.UIAxes, edges(1:end-1), counts, 'FaceColor', [0.35, 0.2, 0.25], 'EdgeColor', 'none'); 

            xlabel(app.UIAxes, 'Time (s)');
            ylabel(app.UIAxes, 'Completed Steps');
            title(app.UIAxes, ['Steps vs Time for ' mountain.Name]);

    
            app.stepsmoretogoTextArea.Value = num2str(stepsLeft(app.currentMountainIndex));

    
            if app.userSteps >= mountain.TotalSteps
                uialert(app.UIFigure, ['Congratulations! You have completed the ascent of ' mountain.Name '!'], 'Success');
            end

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: STARTCHALLENGEButton
        function STARTCHALLENGEButtonPushed(app, event)
            app.VirtualTrekkingPanel.Visible = 'on';

            
            selectedMountain = app.MtEverestDropDown.Value;
         
            app.currentMountainIndex = find(strcmp({app.mountains.Name}, selectedMountain));
            
        
            data = load('feet.mat'); 
            
            app.stepData = data.Acceleration;
            if isempty(app.stepData) || ~any(strcmp(app.stepData.Properties.VariableNames, 'Z'))
                error('No accelerometer data found or Z-axis data is missing.');
            end

           
            app.userSteps = 0;
            app.stepTimes =[];

        % Start the step detection process
            app.calculateSteps();
        end

        % Button pushed function: RESETDATAButton
        function RESETDATAButtonPushed(app, event)
             
            app.userSteps = 0;
            app.stepsmoretogoTextArea.Value = '0'; 
            cla(app.UIAxes);


        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.VirtualHikingChallengePanel = uipanel(app.UIFigure);
            app.VirtualHikingChallengePanel.Title = 'Virtual Hiking Challenge';
            app.MtEverestDropDown = uidropdown(app.VirtualHikingChallengePanel);
            app.MtEverestDropDown.Items = {'Mt. Everest, Nepal', 'Kilimanjaro, Tanzania', 'Annapurna Circuit, Nepal', 'Grand Canyon, USA', 'Inca Trail, Peru', 'Test Trek'};
            app.MtEverestDropDown.Value = 'Inca Trail, Peru';
            app.MountandDewitLabel = uilabel(app.VirtualHikingChallengePanel);
            app.MountandDewitLabel.Text = 'Mount-and Dew it';
            app.STARTCHALLENGEButton = uibutton(app.VirtualHikingChallengePanel, 'push');
            app.STARTCHALLENGEButton.ButtonPushedFcn = createCallbackFcn(app, @STARTCHALLENGEButtonPushed, true);
            app.STARTCHALLENGEButton.Text = 'START CHALLENGE';
            app.Label = uilabel(app.VirtualHikingChallengePanel);
            app.Label.Text = 'Pick your favourite trek destination and let your daily step counter hike!';
            app.VirtualTrekkingPanel = uipanel(app.UIFigure);
            app.VirtualTrekkingPanel.Title = 'Virtual Trekking ';
            app.VirtualTrekkingPanel.Visible = 'off';
            app.UIAxes = uiaxes(app.VirtualTrekkingPanel);
            title(app.UIAxes, 'Step Up your Game!')
            xlabel(app.UIAxes, 'Weekly Progress')
            ylabel(app.UIAxes, 'Your Trek Progress')
            app.RESETDATAButton = uibutton(app.VirtualTrekkingPanel, 'push');
            app.RESETDATAButton.ButtonPushedFcn = createCallbackFcn(app, @RESETDATAButtonPushed, true);
            app.RESETDATAButton.Text = 'RESET DATA';
            app.stepsmoretogoTextAreaLabel = uilabel(app.VirtualTrekkingPanel);
            app.stepsmoretogoTextAreaLabel.Text = 'steps more to go!';
            app.stepsmoretogoTextArea = uitextarea(app.VirtualTrekkingPanel);
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = usecase1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end